classdef mixerCalibration < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EMONATIMSMixerCalibrationv02UIFigure  matlab.ui.Figure
        RunTxCalButton            matlab.ui.control.StateButton
        RunRxCalibrationButton    matlab.ui.control.StateButton
        BuildRxCalFilesButton     matlab.ui.control.StateButton
        AnalogRxFilterCalButton   matlab.ui.control.StateButton
        ReadSingleSidebandButton  matlab.ui.control.StateButton
        SimulatorModeCheckBox     matlab.ui.control.CheckBox
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath('functions\');
        end

        % Callback function
        function AmpSliderValueChanged(app, event)
            
            
        end

        % Value changed function: RunTxCalButton
        function RunTxCalButtonValueChanged(app, event)
            global SIM_MODE
            SIM_MODE = app.SimulatorModeCheckBox.Value;
            readTxCal
            app.RunTxCalButton.Value = 0;
            app.BuildRxCalFilesButton.Enable = 1;
            
        end

        % Value changed function: RunRxCalibrationButton
        function RunRxCalibrationButtonValueChanged(app, event)
            global SIM_MODE
            SIM_MODE = app.SimulatorModeCheckBox.Value;
            readRxCal
            app.RunRxCalibrationButton.Value = 0;
            app.ReadSingleSidebandButton.Enable = 1;
        end

        % Value changed function: BuildRxCalFilesButton
        function BuildRxCalFilesButtonValueChanged(app, event)
            global SIM_MODE
            SIM_MODE = app.SimulatorModeCheckBox.Value;
            buildRxCal
            app.BuildRxCalFilesButton.Value = 0;
            app.RunRxCalibrationButton.Enable = 1;
            
        end

        % Value changed function: AnalogRxFilterCalButton
        function AnalogRxFilterCalButtonValueChanged(app, event)
            
            if isfile('Data Files\filter_tuned.mat') && isfile('Data Files\filter_untuned.mat')
                a = load('Data Files\filter_untuned.mat');
                figure;
                plot(a.tq,a.F1rx,a.tq,a.F2rx);
                grid on; grid minor;
                legend('Filter One','Filter Two');
                title('Simulated Untuned Filters Impulse Response');
                
                a = load('Data Files\filter_tuned.mat');
                figure;
                plot(a.tq,a.F1rx,a.tq,a.F2rx);
                grid on; grid minor;
                legend('Filter One','Filter Two');
                title('Simulated Tuned Filters Impulse Response');
                
            end
            
            global SIM_MODE
            SIM_MODE = app.SimulatorModeCheckBox.Value;
            if ~SIM_MODE
                setRigol_filter;
            end
            
            app.AnalogRxFilterCalButton.Value = 0;
            
        end

        % Value changed function: ReadSingleSidebandButton
        function ReadSingleSidebandButtonValueChanged(app, event)
            global SIM_MODE
            SIM_MODE = app.SimulatorModeCheckBox.Value;
            readRx_sb_calcd
            app.ReadSingleSidebandButton.Value = 0;
            
        end

        % Value changed function: SimulatorModeCheckBox
        function SimulatorModeCheckBoxValueChanged(app, event)
            global SIM_MODE
            SIM_MODE = app.SimulatorModeCheckBox.Value;
            app.BuildRxCalFilesButton.Enable = SIM_MODE;
            app.ReadSingleSidebandButton.Enable = SIM_MODE;
            app.RunRxCalibrationButton.Enable = SIM_MODE;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EMONATIMSMixerCalibrationv02UIFigure = uifigure;
            app.EMONATIMSMixerCalibrationv02UIFigure.Name = 'EMONA TIMS Mixer Calibration v0.2';
            app.RunTxCalButton = uibutton(app.EMONATIMSMixerCalibrationv02UIFigure, 'state');
            app.RunTxCalButton.ValueChangedFcn = createCallbackFcn(app, @RunTxCalButtonValueChanged, true);
            app.RunTxCalButton.Text = 'Run Tx Cal';
            app.RunRxCalibrationButton = uibutton(app.EMONATIMSMixerCalibrationv02UIFigure, 'state');
            app.RunRxCalibrationButton.ValueChangedFcn = createCallbackFcn(app, @RunRxCalibrationButtonValueChanged, true);
            app.RunRxCalibrationButton.Enable = 'off';
            app.RunRxCalibrationButton.Text = 'Run Rx Calibration';
            app.BuildRxCalFilesButton = uibutton(app.EMONATIMSMixerCalibrationv02UIFigure, 'state');
            app.BuildRxCalFilesButton.ValueChangedFcn = createCallbackFcn(app, @BuildRxCalFilesButtonValueChanged, true);
            app.BuildRxCalFilesButton.Enable = 'off';
            app.BuildRxCalFilesButton.Text = 'Build Rx Cal Files';
            app.AnalogRxFilterCalButton = uibutton(app.EMONATIMSMixerCalibrationv02UIFigure, 'state');
            app.AnalogRxFilterCalButton.ValueChangedFcn = createCallbackFcn(app, @AnalogRxFilterCalButtonValueChanged, true);
            app.AnalogRxFilterCalButton.Text = 'Analog Rx Filter Cal';
            app.ReadSingleSidebandButton = uibutton(app.EMONATIMSMixerCalibrationv02UIFigure, 'state');
            app.ReadSingleSidebandButton.ValueChangedFcn = createCallbackFcn(app, @ReadSingleSidebandButtonValueChanged, true);
            app.ReadSingleSidebandButton.Enable = 'off';
            app.ReadSingleSidebandButton.Text = 'Read Single Sideband';
            app.SimulatorModeCheckBox = uicheckbox(app.EMONATIMSMixerCalibrationv02UIFigure);
            app.SimulatorModeCheckBox.ValueChangedFcn = createCallbackFcn(app, @SimulatorModeCheckBoxValueChanged, true);
            app.SimulatorModeCheckBox.Text = 'Simulator Mode';
        end
    end

    methods (Access = public)

        % Construct app
        function app = mixerCalibration

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EMONATIMSMixerCalibrationv02UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EMONATIMSMixerCalibrationv02UIFigure)
        end
    end
end